classdef audioVisualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        playButton    matlab.ui.control.Button
        selectButton  matlab.ui.control.Button
        UIAxes_3      matlab.ui.control.UIAxes
        UIAxes_2      matlab.ui.control.UIAxes
        UIAxes        matlab.ui.control.UIAxes
    end

    properties (Access = public)
        y
        Fs
        playInit
        mus
        
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            app.playInit = -1;


        end

        % Button pushed function: selectButton
        function selectFileFcn(app, event)
            % Allow user to select a .wav file
            [filename, pathname] = uigetfile('*.wav', 'Select a .wav file');

            % Check if a file was selected
            if isequal(filename,0)
                errordlg('Selected file is not a .wav file. Please select a .wav file.', 'File Type Error');
                % User did not select a .wav file
                return;
            end

            % Read the .wav file
            [app.y, app.Fs] = audioread(fullfile(pathname, filename));
            

            % Use the variables y, Fs, and nbits in app as needed

            
            app.mus = audioplayer(app.y,app.Fs);
            %helper fuunction
            
        end

        % Button pushed function: playButton
        function playOrDoneButton(app, event)
            
            [~,cols] = size(app.y);
            x = app.y;
            if cols == 2
                x = mean(app.y, 2)';
            end
            
            
            n = length(app.y);
            Y = fft(app.y)/n;
            f = app.Fs*(0:n-1)/n;  %频谱图

            windowSize = 1024;
            overlap = round(0.5*windowSize);   %声谱图

            [S,F,T] = spectrogram(x, windowSize, overlap, windowSize, app.Fs);  
            
            
            if app.playInit == -1
                app.playInit = 1;
                play(app.mus);
                plot(app.UIAxes,f,20*log10(abs(Y)),'b')
                imagesc(app.UIAxes_2,T,flipud(F),rot90(log(abs(S'))));
                waterfall(app.UIAxes_3,T,F,abs(S));
            elseif app.playInit == 1
                app.playInit = 0;
                stop(app.mus);
                cla(app.UIAxes)
                cla(app.UIAxes_2)
                cla(app.UIAxes_3)
            else
                app.playInit = 1;
                resume(app.mus);
                plot(app.UIAxes,f,20*log10(abs(Y)),'b')
                imagesc(app.UIAxes_2,T,flipud(F),rot90(log(abs(S'))));
                waterfall(app.UIAxes_3,T,F,abs(S));
            end
        
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '频谱图')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '声谱图')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '瀑布图')
            app.selectButton = uibutton(app.UIFigure, 'push');
            app.selectButton.ButtonPushedFcn = createCallbackFcn(app, @selectFileFcn, true);
            app.selectButton.Text = '选择文件';
            app.playButton = uibutton(app.UIFigure, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playOrDoneButton, true);
            app.playButton.Text = '播放';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = audioVisualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end